classdef RayleighFlowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RayleighFlowAnalyzerUIFigure  matlab.ui.Figure
        MainGridLayout                matlab.ui.container.GridLayout
        StatusPanel                   matlab.ui.container.Panel
        statusGrid                    matlab.ui.container.GridLayout
        StatusValueLabel              matlab.ui.control.Label
        ValidationStatusLabel         matlab.ui.control.Label
        ResultsPanel                  matlab.ui.container.Panel
        ResultGrid                    matlab.ui.container.GridLayout
        ResultsTable                  matlab.ui.control.Table
        ActionGrid                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ValidateButton                matlab.ui.control.Button
        GenerateButton                matlab.ui.control.Button
        INPUTPARAMETERPanel           matlab.ui.container.Panel
        InputGrid                     matlab.ui.container.GridLayout
        PointsEditField               matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        PointsLabel                   matlab.ui.control.Label
        MachMaxEditField              matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        MachMaximumLabel              matlab.ui.control.Label
        MachMinEditField              matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        MachminimumLabel              matlab.ui.control.Label
        GammaEditField                matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        GammaLabel                    matlab.ui.control.Label
        HeaderPanel                   matlab.ui.container.Panel
        HeaderGrid                    matlab.ui.container.GridLayout
        VersionLabel                  matlab.ui.control.Label
        CompressibleFlowEngineeringToolLabel  matlab.ui.control.Label
        RAYLEIGHFLOWANALYZERLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            try
                           
            
                %% Read user inputs
        
                gamma   = app.GammaEditField.Value;
                machMin = app.MachMinEditField.Value;
                machMax = app.MachMaxEditField.Value;
                nPoints = app.PointsEditField.Value;
        
        
                %% Validate basic UI input
        
                if machMin <= 0
                    error('RayleighFlowApp:InvalidMachMin', ...
                        'Mach minimum must be greater than zero.');
                end
        
                if machMax <= machMin
                    error('RayleighFlowApp:InvalidMachRange', ...
                        'Mach maximum must be greater than Mach minimum.');
                end
        
                if gamma <= 1
                    error('RayleighFlowApp:InvalidGamma', ...
                        'Specific heat ratio gamma must be greater than 1.');
                end
        
                if nPoints < 2
                    error('RayleighFlowApp:InvalidPointCount', ...
                        'Number of points must be at least 2.');
                end
        
        
                %% Generate Mach vector
        
                M = linspace(machMin, machMax, nPoints);
        
        
                %% Generate Rayleigh-flow table
        
                data = generateRayleighTable(M, gamma);
        
        
                %% Display results in App
        
                app.ResultsTable.Data = data;
        
        
                %% Generate four combined plots
        
                fig = plotCombine(M, gamma);
        
                % Bring combined plot window to front
                figure(fig);
        
        
                %% Update status
        
                app.StatusValueLabel.Text = "READY";
        
        
            catch ME
        
                %% Error handling
        
                app.StatusValueLabel.Text = "ERROR";
        
                uialert( ...
                    app.RayleighFlowAnalyzerUIFigure, ...
                    ME.message, ...
                    "Rayleigh Flow Input Error", ...
                    "Icon", "error");
        
            end

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try
                
                % Read inputs
                gamma = app.GammaEditField.Value;
                machMin = app.MachMinEditField.Value;
                machMax = app.MachMaxEditField.Value;
                nPoints = app.PointsEditField.Value;
        
                % Basic application-level validation
                if gamma <= 1
                    error('RayleighFlowApp:InvalidGamma', ...
                        'Specific heat ratio gamma must be greater than 1.');
                end
        
                if machMin <= 0
                    error('RayleighFlowApp:InvalidMachMin', ...
                        'Mach minimum must be greater than zero.');
                end
        
                if machMax <= machMin
                    error('RayleighFlowApp:InvalidMachRange', ...
                        'Mach maximum must be greater than Mach minimum.');
                end
        
                if nPoints < 2
                    error('RayleighFlowApp:InvalidPointCount', ...
                        'Number of points must be at least 2.');
                end
        
                % Generate Mach vector
                M = linspace(machMin, machMax, nPoints);
        
                % Run engineering validation
                report = validationReport(M, gamma);
        
                % Display status
                if report.overallPass
                    app.StatusValueLabel.Text = "PASS";
                else
                    app.StatusValueLabel.Text = "FAIL";
                end
        
            catch ME
        
                app.StatusValueLabel.Text = "ERROR";
        
                uialert( ...
                    app.RayleighFlowAnalyzerUIFigure, ...
                    ME.message, ...
                    "Validation Error", ...
                    "Icon", "error");
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RayleighFlowAnalyzerUIFigure = uifigure('Visible', 'off');
            app.RayleighFlowAnalyzerUIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.RayleighFlowAnalyzerUIFigure);
            app.HeaderPanel = uipanel(app.MainGridLayout);
            app.HeaderGrid = uigridlayout(app.HeaderPanel);
            app.RAYLEIGHFLOWANALYZERLabel = uilabel(app.HeaderGrid);
            app.RAYLEIGHFLOWANALYZERLabel.Text = 'RAYLEIGH FLOW ANALYZER';
            app.CompressibleFlowEngineeringToolLabel = uilabel(app.HeaderGrid);
            app.CompressibleFlowEngineeringToolLabel.Text = 'Compressible Flow Engineering Tool';
            app.VersionLabel = uilabel(app.HeaderGrid);
            app.VersionLabel.Text = 'v0.7.0';
            app.INPUTPARAMETERPanel = uipanel(app.MainGridLayout);
            app.INPUTPARAMETERPanel.Title = 'INPUT PARAMETER';
            app.InputGrid = uigridlayout(app.INPUTPARAMETERPanel);
            app.GammaLabel = uilabel(app.InputGrid);
            app.GammaLabel.Text = 'Specific Heat Ratio γ';
            app.Label = uilabel(app.InputGrid);
            app.Label.Text = '';
            app.GammaEditField = uieditfield(app.InputGrid, 'numeric');
            app.GammaEditField.Limits = [1 Inf];
            app.GammaEditField.Placeholder = 'Gamma';
            app.GammaEditField.Value = 1.4;
            app.MachminimumLabel = uilabel(app.InputGrid);
            app.MachminimumLabel.Interpreter = 'latex';
            app.MachminimumLabel.Text = '$M_{\mathrm{min}}$';
            app.Label_2 = uilabel(app.InputGrid);
            app.Label_2.Text = '';
            app.MachMinEditField = uieditfield(app.InputGrid, 'numeric');
            app.MachMinEditField.Limits = [0.2 5];
            app.MachMinEditField.Placeholder = 'Mach_Minimum';
            app.MachMinEditField.Value = 0.2;
            app.MachMaximumLabel = uilabel(app.InputGrid);
            app.MachMaximumLabel.Interpreter = 'latex';
            app.MachMaximumLabel.Text = '$M_{\mathrm{max}}$';
            app.Label_3 = uilabel(app.InputGrid);
            app.Label_3.Text = '';
            app.MachMaxEditField = uieditfield(app.InputGrid, 'numeric');
            app.MachMaxEditField.Limits = [2.22044604925031e-16 Inf];
            app.MachMaxEditField.Placeholder = 'Maximum Mach';
            app.MachMaxEditField.Value = 5;
            app.PointsLabel = uilabel(app.InputGrid);
            app.PointsLabel.Text = 'Number Of Point';
            app.Label_4 = uilabel(app.InputGrid);
            app.Label_4.Text = '';
            app.PointsEditField = uieditfield(app.InputGrid, 'numeric');
            app.PointsEditField.Limits = [2 Inf];
            app.PointsEditField.RoundFractionalValues = 'on';
            app.PointsEditField.Placeholder = 'Points';
            app.PointsEditField.Value = 100;
            app.ActionGrid = uipanel(app.MainGridLayout);
            app.ActionGrid.Title = 'Panel';
            app.GridLayout = uigridlayout(app.ActionGrid);
            app.GenerateButton = uibutton(app.GridLayout, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate Table';
            app.ValidateButton = uibutton(app.GridLayout, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate Button';
            app.ResultsPanel = uipanel(app.MainGridLayout);
            app.ResultGrid = uigridlayout(app.ResultsPanel);
            app.ResultsTable = uitable(app.ResultGrid);
            app.ResultsTable.ColumnName = {'Mach'; 'PressureRatio'; 'TemperatureRatio'; 'DensityRatio'; 'VelocityRatio'; 'StagnationTemperatureRatio'; 'StagnationPressureRatio'; 'EntropyOverR'; 'HeatAdditionOverCpTstar'};
            app.ResultsTable.RowName = {};
            app.StatusPanel = uipanel(app.MainGridLayout);
            app.statusGrid = uigridlayout(app.StatusPanel);
            app.ValidationStatusLabel = uilabel(app.statusGrid);
            app.ValidationStatusLabel.Text = 'Validation Status:';
            app.StatusValueLabel = uilabel(app.statusGrid);
            app.StatusValueLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.RayleighFlowAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RayleighFlowApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RayleighFlowAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RayleighFlowAnalyzerUIFigure)
        end
    end
end